classdef President < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Congrats                      matlab.ui.control.Image
        RightLuigi                    matlab.ui.control.Image
        LeftLuigi                     matlab.ui.control.Image
        Fireworks                     matlab.ui.control.Image
        MessageLabel                  matlab.ui.control.Label
        ErrorDisplay                  matlab.ui.control.Image
        SuccessDisplay                matlab.ui.control.Image
        Button_413                    matlab.ui.control.StateButton
        Button_412                    matlab.ui.control.StateButton
        Button_411                    matlab.ui.control.StateButton
        Button_410                    matlab.ui.control.StateButton
        Button_409                    matlab.ui.control.StateButton
        Button_408                    matlab.ui.control.StateButton
        Button_407                    matlab.ui.control.StateButton
        Button_406                    matlab.ui.control.StateButton
        Button_405                    matlab.ui.control.StateButton
        Button_404                    matlab.ui.control.StateButton
        Button_403                    matlab.ui.control.StateButton
        Button_402                    matlab.ui.control.StateButton
        Button_401                    matlab.ui.control.StateButton
        Button_313                    matlab.ui.control.StateButton
        Button_312                    matlab.ui.control.StateButton
        Button_311                    matlab.ui.control.StateButton
        Button_310                    matlab.ui.control.StateButton
        Button_309                    matlab.ui.control.StateButton
        Button_308                    matlab.ui.control.StateButton
        Button_307                    matlab.ui.control.StateButton
        Button_306                    matlab.ui.control.StateButton
        Button_305                    matlab.ui.control.StateButton
        Button_304                    matlab.ui.control.StateButton
        Button_303                    matlab.ui.control.StateButton
        Button_302                    matlab.ui.control.StateButton
        Button_301                    matlab.ui.control.StateButton
        Button_213                    matlab.ui.control.StateButton
        Button_212                    matlab.ui.control.StateButton
        Button_211                    matlab.ui.control.StateButton
        Button_210                    matlab.ui.control.StateButton
        Button_209                    matlab.ui.control.StateButton
        Button_208                    matlab.ui.control.StateButton
        Button_207                    matlab.ui.control.StateButton
        Button_206                    matlab.ui.control.StateButton
        Button_205                    matlab.ui.control.StateButton
        Button_204                    matlab.ui.control.StateButton
        Button_203                    matlab.ui.control.StateButton
        Button_202                    matlab.ui.control.StateButton
        Button_201                    matlab.ui.control.StateButton
        Button_1                      matlab.ui.control.StateButton
        Button_2                      matlab.ui.control.StateButton
        Button_3                      matlab.ui.control.StateButton
        Button_4                      matlab.ui.control.StateButton
        Button_113                    matlab.ui.control.StateButton
        Button_112                    matlab.ui.control.StateButton
        Button_111                    matlab.ui.control.StateButton
        Button_110                    matlab.ui.control.StateButton
        Button_109                    matlab.ui.control.StateButton
        Button_108                    matlab.ui.control.StateButton
        Button_107                    matlab.ui.control.StateButton
        Button_106                    matlab.ui.control.StateButton
        Button_105                    matlab.ui.control.StateButton
        Button_104                    matlab.ui.control.StateButton
        Button_103                    matlab.ui.control.StateButton
        Button_102                    matlab.ui.control.StateButton
        Button_101                    matlab.ui.control.StateButton
        WhosUpPlayerLabel             matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        WhosUpBox                     matlab.ui.control.Image
        ConfirmButton                 matlab.ui.control.Button
        HowManyPlayersEditField       matlab.ui.control.NumericEditField
        HowManyPlayersEditFieldLabel  matlab.ui.control.Label
        HomeButton                    matlab.ui.control.Button
        JoinGameButton                matlab.ui.control.Button
        PlayButton                    matlab.ui.control.Button
        RulesButton                   matlab.ui.control.Button
        Player3Label                  matlab.ui.control.Label
        Player1Label                  matlab.ui.control.Label
        Player2Label                  matlab.ui.control.Label
        Player4Label                  matlab.ui.control.Label
        PlayCardsButton               matlab.ui.control.Button
        SkipTurnButton                matlab.ui.control.Button
        PresidentTitle                matlab.ui.control.Image
        Player3Box                    matlab.ui.control.Image
        Player1Box                    matlab.ui.control.Image
        Player4Box                    matlab.ui.control.Image
        Player2Box                    matlab.ui.control.Image
    end

    
    properties (Access = public)
        cardNum             % Array of all 52 Cards
        playerDecks         % MxN array with M = # of players & N = number of cards
        playedCards = 0     % Cards on the table
        turnCount = 0       % Tracks how many turns have passed
        playerTurn = 1      % Which player's turn is it? Based on playerPriority loop, not actual player number
        skipCount = 0        % # of turns skipped in a round

        numPlayers          % number of player
        gameState           % Is the round over?
        winPlayer = 0       % Who won the round
        playerWins          % Number of wins

    end
    
    methods (Access = public)
        
        % What is the image name of the card?
        function imageArray = CallImage(~, cardArray)
            imageArray = cardArray + ".png";
        end
        
        % Update the icon of button
        function [] = UpdateButtonIcons(app, buttonRange, inputDeck, whichImage)
            if whichImage
                for i = buttonRange
                    buttonName = "Button_" + i;
                    app.(buttonName).Icon = '';
                end
            else
                imageButton = CallImage(app, inputDeck);
                for i = buttonRange
                    buttonName = "Button_" + i;
                    app.(buttonName).Icon = imageButton(mod(i,100));
                end
            end
        end
        
        % Chagne Button Visability
        function [] = UpdateButtonVisible(app, buttonRange, state)
            for i = buttonRange
                buttonName = "Button_" + i;
                app.(buttonName).Visible = state;
            end
        end
        
        % Change value of button
        function [] = UpdateButtonValue(app, buttonRange, state)
            for i = buttonRange
                buttonName = "Button_" + i;
                app.(buttonName).Value = state;
            end
         end

        % Check button states
        function statesArray = CheckButtonStates(app, buttonRange)
            statesArray = zeros(1, length(buttonRange));
            for i = buttonRange
                buttonName = "Button_" + i;
                statesArray(mod(i,100)) = app.(buttonName).Value;
            end
            statesArray = logical(statesArray);
        end

        function [] = IncreasePlayerTurn(app)
            app.turnCount = app.turnCount + 1;
            app.playerTurn = mod(app.turnCount, app.numPlayers);
                if app.playerTurn == 0
                    app.playerTurn = app.numPlayers;
                end 
            fprintf("The player turn has been increased to: %g \n", app.playerTurn)
        end


        function [] = victorySound(app)
            file_path = 'C:\Users\chris\OneDrive\Documents\MATLAB\eng6final\src\audio\ff7.mp3';
            [audio_data, sample_rate] = audioread(file_path);
            sound(audio_data, sample_rate);            
        end
        
        function [] = cardSound(app)
        file_path = 'C:\Users\chris\OneDrive\Documents\MATLAB\eng6final\src\audio\Card_dealt.mp3';
            [audio_data, sample_rate] = audioread(file_path);
            sound(audio_data, sample_rate);       
            
        end
        
        function [] = lossSound(app)
            file_path = 'C:\Users\chris\OneDrive\Documents\MATLAB\eng6final\src\audio\losing.mp3';
            [audio_data, sample_rate] = audioread(file_path);
            sound(audio_data, sample_rate);  
        end


        function [] = backgroundMusic(app)
            file_path = 'C:\Users\chris\OneDrive\Documents\MATLAB\eng6final\src\audio\background music.mp3';
            [audio_data, sample_rate] = audioread(file_path);
            audioDampener = .01
            sound(audio_data*audioDampener, sample_rate);
        end
            end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            fprintf("\nA new session has been started \n")
            
            app.UIFigure.WindowState = 'maximized';
            app.RulesButton.Visible = "on";
            app.Player1Label.Visible = "off";
            app.Player2Label.Visible = "off";
            app.Player3Label.Visible = "off";
            app.Player4Label.Visible = "off";
            app.Label.Visible = "off";
            app.WhosUpPlayerLabel.Visible = "off";
            app.PlayCardsButton.Visible = "off";
            app.SkipTurnButton.Visible = "off";
            app.WhosUpBox.Visible = "off";
            app.Player3Box.Visible = "off";
            app.Player1Box.Visible = "off";
            app.PresidentTitle.Visible = "on";
            app.WhosUpPlayerLabel.Visible ="off";
            app.Player4Box.Visible = "off";
            app.Player2Box.Visible = "off";
            app.HomeButton.Visible = "off";
            app.HowManyPlayersEditField.Visible= "off";
            app.HowManyPlayersEditFieldLabel.Visible = "off";
            app.ConfirmButton.Visible ="off";
            UpdateButtonVisible(app, [1:4, 101:113, 201:213,301:313,401:413], "off")
            app.MessageLabel.Visible = "off";
            app.SuccessDisplay.Visible = "off";
            app.ErrorDisplay.Visible = "off";
            app.Fireworks.Visible = "off";
            app.LeftLuigi.Visible = "off";
            app.RightLuigi.Visible = "off";
            app.Congrats.Visible = "off";
            backgroundMusic(app)

        end

        % Button pushed function: RulesButton
        function RulesButtonPushed(app, event)
            RulesPopup
            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            fprintf("A new lobby has been opened \n")
            drawnow;
            app.UIFigure.WindowState = 'maximized';
            app.RulesButton.Visible = "on";
            app.Player1Label.Visible = "off";
            app.Player2Label.Visible = "off";
            app.Player3Label.Visible = "off";
            app.Player4Label.Visible = "off";
            app.Label.Visible = "off";
            app.WhosUpPlayerLabel.Visible = "off";
            app.PlayCardsButton.Visible = "off";
            app.SkipTurnButton.Visible = "off";
            app.WhosUpBox.Visible = "off";
            app.Player3Box.Visible = "off";
            app.Player1Box.Visible = "off";
            app.PresidentTitle.Visible = "on"; %% keep president image up 
            app.Player4Box.Visible = "off";
            app.Player2Box.Visible = "off";
            app.PlayButton.Visible = "off";
            app.JoinGameButton.Visible = "off";
            app.HowManyPlayersEditField.Visible ="on";
            app.HowManyPlayersEditFieldLabel.Visible = "on";
            app.ConfirmButton.Visible ="on";


        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            % Game Initialization 
            app.numPlayers = app.HowManyPlayersEditField.Value;
            app.turnCount = 1;
            
            %How many players are there?
            if (app.numPlayers >= 2) && (app.numPlayers <= 4)
                
                % Change Scene
                app.RulesButton.Visible = "on";
                app.Label.Visible = "on";
                app.WhosUpPlayerLabel.Visible = "on";
                app.PlayCardsButton.Visible = "on";
                app.SkipTurnButton.Visible = "on";
                app.WhosUpBox.Visible = "on";
                app.PresidentTitle.Visible = "on"; %% keep president image up
                app.PlayButton.Visible = "off";
                app.JoinGameButton.Visible = "off";
                app.HowManyPlayersEditField.Visible ="off";
                app.HowManyPlayersEditFieldLabel.Visible = "off";
                app.ConfirmButton.Visible ="off";
                app.SuccessDisplay.Visible ="on";
                % Show cards for number of players
                switch app.HowManyPlayersEditField.Value
                    case 2
                        app.Player1Label.Visible = "on";
                        app.Player2Label.Visible = "on";
                        app.Player3Label.Visible = "off";
                        app.Player4Label.Visible = "off";
                        app.Player1Box.Visible = "on";
                        app.Player2Box.Visible = "on";
                        app.Player3Box.Visible = "off";
                        app.Player4Box.Visible = "off";
                        UpdateButtonVisible(app, [1:4, 101:113, 201:213], "on")
                    case 3
                        app.Player1Label.Visible = "on";
                        app.Player2Label.Visible = "on";
                        app.Player3Label.Visible = "on";
                        app.Player4Label.Visible = "off";
                        app.Player1Box.Visible = "on";
                        app.Player2Box.Visible = "on";
                        app.Player3Box.Visible = "on";
                        app.Player4Box.Visible = "off";
                        UpdateButtonVisible(app, [1:4, 101:113, 201:213,301:313], "on")
                    case 4
                        app.Player1Label.Visible = "on";
                        app.Player2Label.Visible = "on";
                        app.Player3Label.Visible = "on";
                        app.Player4Label.Visible = "on";
                        app.Player1Box.Visible = "on";
                        app.Player2Box.Visible = "on";
                        app.Player3Box.Visible = "on";
                        app.Player4Box.Visible = "on";
                        UpdateButtonVisible(app, [1:4, 101:113, 201:213, 301:313,401:413], "on")
                end
                
                %% Generate Card Decks
                app.cardNum = randperm(52);
               
                app.playerDecks = zeros(app.numPlayers, 13);
                for i = 1:app.numPlayers
                    app.playerDecks(i, :) = app.cardNum((13*i - 12): 13*i);
                    UpdateButtonIcons(app, (i*100) + (1:13), app.playerDecks(i,:), false);
                end
                
                fprintf("The following decks have been created: \n")
                disp(app.playerDecks)

            else
                fprintf("Number of players not supported \n")
            end
            
        end

        % Button pushed function: PlayCardsButton
        function PlayCardsButtonPushed(app, event)
            %% Initalization
            fprintf("The current player turn is: %g \n", app.playerTurn);
                 
                
            % Check button states and make array
            playerButtons = app.playerTurn*100 + (1:13);
            buttonStates = CheckButtonStates(app, playerButtons);
            selectedCards = app.playerDecks(app.playerTurn,buttonStates);           
            
            % Define values to be used for rest of function
            selectedSetSize = length(selectedCards);
            selectedCardValue = mod(selectedCards, 13);
                if selectedCardValue == 0
                    selectedCardValue = 13;
                end 

            playedSetSize = length(app.playedCards);
            playedCardValue = mod(app.playedCards, 13);
                if playedCardValue == 0
                    playedCardValue = 13;
                end
            
            %% Unsucsessful Turn Logic
            % Have card(s) be selected
            if isempty(selectedCards)
                fprintf("Please select at least one card \n")
                app.ErrorDisplay.Visible = "on";
                app.MessageLabel.Visible = "on";
                errorMessageMatch = "Play anything lol";
                app.MessageLabel.Text = errorMessageMatch;
                pause(3);
                app.MessageLabel.Visible ="off";
                app.ErrorDisplay.Visible = "off";
                return
            end

            % Check if cards played are of matching value
            if selectedSetSize > 1
                for i = 1:(selectedSetSize - 1)
                    if not(selectedCardValue(i) == selectedCardValue(i+1))
                        fprintf("Cards %g are not of matching value \n", selectedCards(:))
                        app.ErrorDisplay.Visible = "on";
                        app.MessageLabel.Visible = "on";
                        errorMessageMatch = "Error, try again.";
                        app.MessageLabel.Text = errorMessageMatch;
                        pause(3);
                        app.MessageLabel.Visible ="off";
                        app.ErrorDisplay.Visible = "off";
                        return
                    end
                end
            end
            
            % Check if number of cards played matches previous play
            if any(app.playedCards) && not(selectedSetSize == playedSetSize)
               disp(selectedSetSize);
               fprintf("The deck of %g does not match the size of the previous play \n", selectedCards)
               app.ErrorDisplay.Visible = "on";
                app.MessageLabel.Visible = "on";
                errorMessageMatch = "Match the amount.";
                app.MessageLabel.Text = errorMessageMatch;
                pause(3);
                app.MessageLabel.Visible ="off";
                app.ErrorDisplay.Visible = "off";
               return
            end
            
            %% Sucessful Turn Logic
            %Check if cards played are higher value
            if (selectedCardValue(1) > playedCardValue(1)) || (app.playedCards == 0)
                app.playedCards = selectedCards;
                CallImage(app, app.playedCards);
                fprintf("Cards %g played sucessfully! \n", app.playedCards)
                app.SuccessDisplay.Visible = "on";
                successMessage = "Succesful move";
                app.MessageLabel.Visible = "on";
                app.MessageLabel.Text = successMessage;
                pause(3)
                app.MessageLabel.Visible = "off";
                if app.numPlayers ==4 
                if app.playerTurn == 1
                turn1Message = string(2);
                app.Label.Text = turn1Message;
                else 
                    if app.playerTurn ==2
                    app.Label.Text = string(3);
                    else 
                        if app.playerTurn == 3 
                    app.Label.Text = string(4);
                        else 
                            if app.playerTurn == 4
                    app.Label.Text = string(1);

                else 
                            end
                        end
                    end
                end 
                end
                if app.numPlayers ==3
                if app.playerTurn == 1
                turn1Message = string(2);
                app.Label.Text = turn1Message;
                else 
                    if app.playerTurn ==2
                    app.Label.Text = string(3);
                else 
                    if app.playerTurn == 3 
                    app.Label.Text = string(1);
                else 
             
                     end 
                     end
                end
                end
           if app.numPlayers ==2
                if app.playerTurn == 1
                app.Label.Text = string(2);
                else 
                    if app.playerTurn ==2
                    app.Label.Text = string(1);
                     else
                     end
                end
           end
disp(app.playerDecks);

% ******************************************************************************************************
                % Kill cards from deck
                position = find(app.playerDecks(app.playerTurn, :) == app.playedCards(:)); %% was playedTurns in fidn2nmd
                app.playerDecks(app.playerTurn, position) = 0;
                fprintf("The current decks are: \n")
                disp(app.playerDecks);
%*********************************************************************************************************
                % Make Button Disapear
                buttonsOff = playerButtons(buttonStates);
                UpdateButtonVisible(app, buttonsOff, "off")
                UpdateButtonValue(app, buttonsOff, 0)
                cardSound(app)

                % Set Center Buttons
                UpdateButtonIcons(app, 1:4, resize(app.playedCards, 4), false)
                
                % Increase Player Turn
                IncreasePlayerTurn(app);


                %% Check Winstate
                for i = 1:app.numPlayers
                    playersHand = app.playerDecks(i,:); %isolates one player's hand
                    summedHand = sum(playersHand); %sums all the values of that players hand

                    if summedHand == 0  %Checks if hand sums to 0, meaning the hand is empty
                        app.gameState = "Round Complete";
                        fprintf("Player %g has won! \n", i)
                        app.winPlayer = i;
                        UpdateButtonVisible(app, [1:4, 101:113, 201:213,301:313,401:413], "off")
                        app.HomeButton.Visible = "on";
                        app.Player1Label.Visible = "off";
                        app.Player2Label.Visible = "off";
                        app.Player3Label.Visible = "off";
                        app.Player4Label.Visible = "off";
                        app.Player1Label.Visible = "off";
                        app.Player2Label.Visible = "off";
                        app.Player3Label.Visible = "off";
                        app.Player4Label.Visible = "off";
                        app.Label.Visible = "off";
                        app.WhosUpBox.Visible = "off";
                        app.WhosUpPlayerLabel.Visible = "off";
                        app.PlayCardsButton.Visible = "off";
                        app.SkipTurnButton.Visible = "off";
                        app.SuccessDisplay.Visible = "off";
                        app.Player1Box.Visible ="off";
                        app.Player2Box.Visible ="off";
                        app.Player3Box.Visible ="off";
                        app.Player4Box.Visible ="off";
                        
                        clear sound 
                        victorySound(app)
                        app.Fireworks.Visible = "on";
                        app.LeftLuigi.Visible = "on";
                        app.RightLuigi.Visible = "on";
                        app.Congrats.Visible = "on";

                        
                        break
                    else 
                        app.gameState = "Round Incomplete";
                        fprintf("Player %g has still not won \n", i)
                        app.winPlayer = 0 ;
                    end 
                end 
            else
                fprintf("The current value on the table is %g \n", playedCardValue)
                fprintf("Value of cards %g ia not high enough \n", selectedCardValue)
                errorValue = "Value of card(s) too low.";
                app.MessageLabel.Visible = "on";
                app.ErrorDisplay.Visible ="on";
                app.MessageLabel.Text = errorValue;
                pause(3)
                app.MessageLabel.Visible = "off";
                app.ErrorDisplay.Visible = "off";
       
            end
        end

        % Button pushed function: SkipTurnButton
        function SkipTurnButtonPushed(app, event)

            %Increase skip Count
            app.skipCount = app.skipCount + 1;
            test = app.numPlayers;
            test2 = string(test);
            app.Label.Text = test2;

            %Check if all players have skip, initialize new round, set next playerTurn
            if app.skipCount == app.numPlayers - 1
                roundWinner = mod(app.turnCount, app.numPlayers) + 1; 
                app.skipCount = 0;
                app.playedCards = 0;
                UpdateButtonIcons(app, 1:4, app.playedCards, true)

                fprintf("Player %g has won the round! \n", roundWinner);
                app.SuccessDisplay.Visible = "on";
                app.MessageLabel.Visible = "on";

                winMessageMatch = "Player "+ roundWinner +" has won the round.";
                app.MessageLabel.Text = winMessageMatch;
                pause(3);
                app.MessageLabel.Visible ="off";
                app.SuccessDisplay.Visible = "on";
            end
                    if app.numPlayers ==4 
                if app.playerTurn == 1
                turn1Message = string(2);
                app.Label.Text = turn1Message;
                else 
                    if app.playerTurn ==2
                    app.Label.Text = string(3);
                    else 
                        if app.playerTurn == 3 
                    app.Label.Text = string(4);
                        else 
                            if app.playerTurn == 4
                    app.Label.Text = string(1);

                else 
                            end
                        end
                    end
                end 
                    end
                if app.numPlayers ==3
                if app.playerTurn == 1
                turn1Message = string(2);
                app.Label.Text = turn1Message;
                else 
                    if app.playerTurn ==2
                    app.Label.Text = string(3);
                    else 
                        if app.playerTurn == 3 
                    app.Label.Text = string(1);
                else 
             
                        end 
                     end
                end
                end
           if app.numPlayers ==2
                if app.playerTurn == 1
                app.Label.Text = string(2);
                else 
                    if app.playerTurn ==2
                    app.Label.Text = string(1);
                     else
                     end
                end
           end
             %Increase Player Turn     
              IncreasePlayerTurn(app);
            
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            app.RulesButton.Visible = "on";
            app.Player1Label.Visible = "off";
            app.Player2Label.Visible = "off";
            app.Player3Label.Visible = "off";
            app.Player4Label.Visible = "off";
            app.Label.Visible = "off";
            app.WhosUpPlayerLabel.Visible = "off";
            app.PlayCardsButton.Visible = "off";
            app.SkipTurnButton.Visible = "off";
            app.WhosUpBox.Visible = "off";
            app.Player3Box.Visible = "off";
            app.Player1Box.Visible = "off";
            app.PresidentTitle.Visible = "on";
            app.Player4Box.Visible = "off";
            app.Player2Box.Visible = "off";
            app.HomeButton.Visible = "off";
            backgroundMusic(app)
            app.PlayButton.Visible="on"
            app.JoinGameButton.Visible="on"

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
clear sound; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Player2Box = uiimage(app.UIFigure);
            app.Player2Box.ImageSource = fullfile(pathToMLAPP, 'imagescrying', 'background.png');
            app.Player4Box = uiimage(app.UIFigure);
            app.Player4Box.ImageSource = fullfile(pathToMLAPP, 'imagescrying', 'background.png');
            app.Player1Box = uiimage(app.UIFigure);
            app.Player1Box.ImageSource = fullfile(pathToMLAPP, 'imagescrying', 'background.png');
            app.Player3Box = uiimage(app.UIFigure);
            app.Player3Box.ImageSource = fullfile(pathToMLAPP, 'imagescrying', 'background.png');
            app.PresidentTitle = uiimage(app.UIFigure);
            app.PresidentTitle.ImageSource = fullfile(pathToMLAPP, 'imagescrying', 'President Title.png');
            app.SkipTurnButton = uibutton(app.UIFigure, 'push');
            app.SkipTurnButton.ButtonPushedFcn = createCallbackFcn(app, @SkipTurnButtonPushed, true);
            app.SkipTurnButton.Text = 'Skip Turn';
            app.PlayCardsButton = uibutton(app.UIFigure, 'push');
            app.PlayCardsButton.ButtonPushedFcn = createCallbackFcn(app, @PlayCardsButtonPushed, true);
            app.PlayCardsButton.Text = 'Play Cards';
            app.Player4Label = uilabel(app.UIFigure);
            app.Player4Label.Text = 'Player 4';
            app.Player2Label = uilabel(app.UIFigure);
            app.Player2Label.Text = 'Player 2';
            app.Player1Label = uilabel(app.UIFigure);
            app.Player1Label.Text = 'Player 1';
            app.Player3Label = uilabel(app.UIFigure);
            app.Player3Label.Text = 'Player 3';
            app.RulesButton = uibutton(app.UIFigure, 'push');
            app.RulesButton.ButtonPushedFcn = createCallbackFcn(app, @RulesButtonPushed, true);
            app.RulesButton.Text = 'Rules';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.JoinGameButton = uibutton(app.UIFigure, 'push');
            app.JoinGameButton.Text = 'Join Game';
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.HowManyPlayersEditFieldLabel = uilabel(app.UIFigure);
            app.HowManyPlayersEditFieldLabel.Text = 'How Many Players?';
            app.HowManyPlayersEditField = uieditfield(app.UIFigure, 'numeric');
            app.HowManyPlayersEditField.Value = 2;
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.WhosUpBox = uiimage(app.UIFigure);
            app.WhosUpBox.ImageSource = fullfile(pathToMLAPP, 'imagescrying', 'background bigger.png');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1';
            app.WhosUpPlayerLabel = uilabel(app.UIFigure);
            app.WhosUpPlayerLabel.Text = {'Who''s Up:'; 'Player'};
            app.Button_101 = uibutton(app.UIFigure, 'state');
            app.Button_101.Text = '';
            app.Button_102 = uibutton(app.UIFigure, 'state');
            app.Button_102.Text = '';
            app.Button_103 = uibutton(app.UIFigure, 'state');
            app.Button_103.Text = '';
            app.Button_104 = uibutton(app.UIFigure, 'state');
            app.Button_104.Text = '';
            app.Button_105 = uibutton(app.UIFigure, 'state');
            app.Button_105.Text = '';
            app.Button_106 = uibutton(app.UIFigure, 'state');
            app.Button_106.Text = '';
            app.Button_107 = uibutton(app.UIFigure, 'state');
            app.Button_107.Text = '';
            app.Button_108 = uibutton(app.UIFigure, 'state');
            app.Button_108.Text = '';
            app.Button_109 = uibutton(app.UIFigure, 'state');
            app.Button_109.Text = '';
            app.Button_110 = uibutton(app.UIFigure, 'state');
            app.Button_110.Text = '';
            app.Button_111 = uibutton(app.UIFigure, 'state');
            app.Button_111.Text = '';
            app.Button_112 = uibutton(app.UIFigure, 'state');
            app.Button_112.Text = '';
            app.Button_113 = uibutton(app.UIFigure, 'state');
            app.Button_113.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'state');
            app.Button_4.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'state');
            app.Button_3.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'state');
            app.Button_2.Text = '';
            app.Button_1 = uibutton(app.UIFigure, 'state');
            app.Button_1.Text = '';
            app.Button_201 = uibutton(app.UIFigure, 'state');
            app.Button_201.Text = '';
            app.Button_202 = uibutton(app.UIFigure, 'state');
            app.Button_202.Text = '';
            app.Button_203 = uibutton(app.UIFigure, 'state');
            app.Button_203.Text = '';
            app.Button_204 = uibutton(app.UIFigure, 'state');
            app.Button_204.Text = '';
            app.Button_205 = uibutton(app.UIFigure, 'state');
            app.Button_205.Text = '';
            app.Button_206 = uibutton(app.UIFigure, 'state');
            app.Button_206.Text = '';
            app.Button_207 = uibutton(app.UIFigure, 'state');
            app.Button_207.Text = '';
            app.Button_208 = uibutton(app.UIFigure, 'state');
            app.Button_208.Text = '';
            app.Button_209 = uibutton(app.UIFigure, 'state');
            app.Button_209.Text = '';
            app.Button_210 = uibutton(app.UIFigure, 'state');
            app.Button_210.Text = '';
            app.Button_211 = uibutton(app.UIFigure, 'state');
            app.Button_211.Text = '';
            app.Button_212 = uibutton(app.UIFigure, 'state');
            app.Button_212.Text = '';
            app.Button_213 = uibutton(app.UIFigure, 'state');
            app.Button_213.Text = '';
            app.Button_301 = uibutton(app.UIFigure, 'state');
            app.Button_301.Text = '';
            app.Button_302 = uibutton(app.UIFigure, 'state');
            app.Button_302.Text = '';
            app.Button_303 = uibutton(app.UIFigure, 'state');
            app.Button_303.Text = '';
            app.Button_304 = uibutton(app.UIFigure, 'state');
            app.Button_304.Text = '';
            app.Button_305 = uibutton(app.UIFigure, 'state');
            app.Button_305.Text = '';
            app.Button_306 = uibutton(app.UIFigure, 'state');
            app.Button_306.Text = '';
            app.Button_307 = uibutton(app.UIFigure, 'state');
            app.Button_307.Text = '';
            app.Button_308 = uibutton(app.UIFigure, 'state');
            app.Button_308.Text = '';
            app.Button_309 = uibutton(app.UIFigure, 'state');
            app.Button_309.Text = '';
            app.Button_310 = uibutton(app.UIFigure, 'state');
            app.Button_310.Text = '';
            app.Button_311 = uibutton(app.UIFigure, 'state');
            app.Button_311.Text = '';
            app.Button_312 = uibutton(app.UIFigure, 'state');
            app.Button_312.Text = '';
            app.Button_313 = uibutton(app.UIFigure, 'state');
            app.Button_313.Text = '';
            app.Button_401 = uibutton(app.UIFigure, 'state');
            app.Button_401.Text = '';
            app.Button_402 = uibutton(app.UIFigure, 'state');
            app.Button_402.Text = '';
            app.Button_403 = uibutton(app.UIFigure, 'state');
            app.Button_403.Text = '';
            app.Button_404 = uibutton(app.UIFigure, 'state');
            app.Button_404.Text = '';
            app.Button_405 = uibutton(app.UIFigure, 'state');
            app.Button_405.Text = '';
            app.Button_406 = uibutton(app.UIFigure, 'state');
            app.Button_406.Text = '';
            app.Button_407 = uibutton(app.UIFigure, 'state');
            app.Button_407.Text = '';
            app.Button_408 = uibutton(app.UIFigure, 'state');
            app.Button_408.Text = '';
            app.Button_409 = uibutton(app.UIFigure, 'state');
            app.Button_409.Text = '';
            app.Button_410 = uibutton(app.UIFigure, 'state');
            app.Button_410.Text = '';
            app.Button_411 = uibutton(app.UIFigure, 'state');
            app.Button_411.Text = '';
            app.Button_412 = uibutton(app.UIFigure, 'state');
            app.Button_412.Text = '';
            app.Button_413 = uibutton(app.UIFigure, 'state');
            app.Button_413.Text = '';
            app.SuccessDisplay = uiimage(app.UIFigure);
            app.SuccessDisplay.ImageSource = fullfile(pathToMLAPP, 'imagescrying', 'success background.png');
            app.ErrorDisplay = uiimage(app.UIFigure);
            app.ErrorDisplay.ImageSource = fullfile(pathToMLAPP, 'imagescrying', 'error background.png');
            app.MessageLabel = uilabel(app.UIFigure);
            app.MessageLabel.Text = 'test';
            app.Fireworks = uiimage(app.UIFigure);
            app.Fireworks.ImageSource = fullfile(pathToMLAPP, 'imagescrying', 'Fireworks.gif');
            app.LeftLuigi = uiimage(app.UIFigure);
            app.LeftLuigi.ImageSource = 'LuigiVibin.gif';
            app.RightLuigi = uiimage(app.UIFigure);
            app.RightLuigi.ImageSource = fullfile(pathToMLAPP, 'imagescrying', 'LuigiVibin.gif');
            app.Congrats = uiimage(app.UIFigure);
            app.Congrats.ImageSource = fullfile(pathToMLAPP, 'imagescrying', 'Congrats!.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = President

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end